classdef RCluster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        tSNEoptionPanel         matlab.ui.container.Panel
        PerplexitySpinnerLabel  matlab.ui.control.Label
        PerplexitySpinner       matlab.ui.control.Spinner
        DistanceDropDownLabel   matlab.ui.control.Label
        DistanceDropDown        matlab.ui.control.DropDown
        StandarizeSwitchLabel   matlab.ui.control.Label
        StandarizeSwitch        matlab.ui.control.Switch
        RefreshButton           matlab.ui.control.Button
        DataoperationPanel      matlab.ui.container.Panel
        SelectNewRegionButton   matlab.ui.control.Button
        OutputPanel             matlab.ui.container.Panel
        CopyButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        rdata % Description
        hp
        id
        isStandarize
        distance
        perplexity
        xy
        rpath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, rdata, rpath, parent, id)
            app.rdata = rdata;
            app.hp = parent;
            app.id = id;
            app.isStandarize = 0;
            app.distance = app.DistanceDropDown.Value;
            app.perplexity = app.PerplexitySpinner.Value;
            app.rpath = rpath;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            hbar = waitbar(0,'Please wait...');
            app.xy = tsne(app.rdata.mat,"Distance",app.distance,"Perplexity",app.perplexity,"Standardize",app.isStandarize);
            waitbar(1,hbar,'Done');
            h = gscatter(app.UIAxes,app.xy(:,1),app.xy(:,2),app.rdata.tag);
            for m = 1:length(h)
                h(m).DisplayName = app.rdata.sampleNames{m};
                h(m).MarkerSize = 10;
            end
            close(hbar);
        end

        % Value changed function: PerplexitySpinner
        function PerplexitySpinnerValueChanged(app, event)
            value = app.PerplexitySpinner.Value;
            app.perplexity = value;
        end

        % Value changed function: DistanceDropDown
        function DistanceDropDownValueChanged(app, event)
            value = app.DistanceDropDown.Value;
            app.distance = value;
        end

        % Value changed function: StandarizeSwitch
        function StandarizeSwitchValueChanged(app, event)
            value = app.StandarizeSwitch.Value;
            app.isStandarize = strcmp(value,'On');
        end

        % Button pushed function: SelectNewRegionButton
        function SelectNewRegionButtonPushed(app, event)
            app.SelectNewRegionButton.BackgroundColor = lines(1);
            app.SelectNewRegionButton.Text = 'Please draw...';
            isIn = selectPoints(app.UIAxes,app.xy);
            newR = struct();
            newR.mat = app.rdata.mat(isIn,:);
            newR.mz = app.rdata.mz;
            newR.tag = app.rdata.tag(isIn);
            tagLeft = unique(newR.tag);
            nTag = length(tagLeft);
            newR.sampleNames = cell(1,nTag);
            tmpTag = zeros(sum(isIn),1);
            newR.nCell = zeros(nTag,1);
            for m = 1:nTag
                tmpTag(newR.tag==tagLeft(m)) = m;
                newR.sampleNames{m} = app.rdata.sampleNames{tagLeft(m)};
                newR.nCell(m) = sum(newR.tag==tagLeft(m));
            end
            newR.tag = tmpTag;
            hwin = RViewer(newR,app.rpath,app.hp,app.hp.IDCount);
            app.hp.registNewWindow(hwin);
            app.SelectNewRegionButton.BackgroundColor = 0.96*ones(1,3);
            app.SelectNewRegionButton.Text = 'Select new region';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.hp.removeWindow(app.id);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.tSNEoptionPanel = uipanel(app.UIFigure);
            app.tSNEoptionPanel.Title = 't-SNE option';
            app.PerplexitySpinnerLabel = uilabel(app.tSNEoptionPanel);
            app.PerplexitySpinnerLabel.Text = 'Perplexity';
            app.PerplexitySpinner = uispinner(app.tSNEoptionPanel);
            app.PerplexitySpinner.Limits = [5 300];
            app.PerplexitySpinner.ValueChangedFcn = createCallbackFcn(app, @PerplexitySpinnerValueChanged, true);
            app.PerplexitySpinner.Value = 30;
            app.DistanceDropDownLabel = uilabel(app.tSNEoptionPanel);
            app.DistanceDropDownLabel.Text = 'Distance';
            app.DistanceDropDown = uidropdown(app.tSNEoptionPanel);
            app.DistanceDropDown.Items = {'euclidean', 'seuclidean', 'chebychev', 'mahalanobis', 'cosine', 'correlation', 'jaccard', 'hamming'};
            app.DistanceDropDown.ValueChangedFcn = createCallbackFcn(app, @DistanceDropDownValueChanged, true);
            app.DistanceDropDown.Value = 'euclidean';
            app.StandarizeSwitchLabel = uilabel(app.tSNEoptionPanel);
            app.StandarizeSwitchLabel.Text = 'Standarize';
            app.StandarizeSwitch = uiswitch(app.tSNEoptionPanel, 'slider');
            app.StandarizeSwitch.ValueChangedFcn = createCallbackFcn(app, @StandarizeSwitchValueChanged, true);
            app.RefreshButton = uibutton(app.tSNEoptionPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DataoperationPanel = uipanel(app.UIFigure);
            app.DataoperationPanel.Title = 'Data operation';
            app.SelectNewRegionButton = uibutton(app.DataoperationPanel, 'push');
            app.SelectNewRegionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNewRegionButtonPushed, true);
            app.SelectNewRegionButton.Text = 'Select New Region';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.CopyButton = uibutton(app.OutputPanel, 'push');
            app.CopyButton.Text = 'Copy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RCluster(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end