classdef RSite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RSiteMain          matlab.ui.Figure
        OpenButton         matlab.ui.control.Button
        FunctionsPanel     matlab.ui.container.Panel
        ActiveWindowLabel  matlab.ui.control.Label
        winNameLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        ChildWindows
        ActiveChild
    end
    
    properties (Access = public)
        IDCount % Description
    end
    
    methods (Access = private)
        
        function results = funcCallBack(app,source,event)
            [h,name] = app.getCurrentWindow();
            if strcmp(name,'RViewer')
                rdata = h.getData();
                eval(sprintf('newh = %s(rdata,"%s",app,%d)',source.Text,h.rpath,app.IDCount));
                app.registNewWindow(newh);
            else
                errordlg('RSite: active window is not RViewer!')
            end
        end
        
        function [h,name] = getCurrentWindow(app)
            if isnan(app.ActiveChild)
                errordlg('RSite: no valid windows found!')
                h = 0;
                name = '';
            else
                h = app.ChildWindows.data{app.winID2pos(app.ActiveChild)};
                h = h.handle;
                name = class(h);
            end
        end
        
        
        function results = curWinIds(app)
            results = cellfun(@(x)x.id,app.ChildWindows.toCell);
        end
        
        function I = winID2pos(app,id)
            [~,I] = ismember(id,app.curWinIds);
        end
    end
    
    methods (Access = public)
        
        function setActiveFigure(app,id)
            app.ActiveChild = id;
            if ~isnan(id)
                app.winNameLabel.Text = sprintf('%s-%d',class( app.ChildWindows.data{app.winID2pos(id)}.handle),id);
            else
                app.winNameLabel.Text = 'None';
            end
        end
        
        function registNewWindow(app,h)
            window = struct();
            window.handle = h;
            window.id = app.IDCount;
            app.ChildWindows.addOne(window);
            app.setActiveFigure(window.id);
            app.IDCount = app.IDCount+1;
        end
        
        function removeWindow(app,id)
            I = app.winID2pos(id);
            if I<0
                errordlg(sprintf('RSite: no window with id=%d was found!',id));
            else
                if id == app.ActiveChild
                    app.setActiveFigure(nan);                   
                end
                app.ChildWindows.delEles(I);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            INIT_POS = 350;
            OFFSET = 40;
            app.IDCount = 0;
            app.ChildWindows = matList();
            homeDir = which('Rsite.mlapp');
            funcDir = strcat(homeDir(1:(end-11)),'Plugins\');
            list = ls(funcDir);
            N = size(list,1);
            if N < 3
                warndlg('No function found!','RSite');
            else
                for m = 1:(N-2)
                    funcName = strtrim(list(m+2,:));
                    %Create function button
                    FButton = uibutton(app.FunctionsPanel, 'push');
                    FButton.FontSize = 14;
                    FButton.FontWeight = 'bold';
                    FButton.Position = [15,INIT_POS - (m-1)*OFFSET,230,30];
                    FButton.Text = funcName;
                    FButton.ButtonPushedFcn = @(s,e)app.funcCallBack(s,e);
                end
            end
            app.setActiveFigure(nan);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [fn,fp,index] = uigetfile('*.mat');
            if index
                data = load(strcat(fp,fn));
                h = RViewer(data.r,strcat(fp,fn),app,app.IDCount);
                app.registNewWindow(h);
            end
        end

        % Close request function: RSiteMain
        function RSiteMainCloseRequest(app, event)
            for m = 1:app.ChildWindows.len
                delete(app.ChildWindows.data{m}.handle)
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RSiteMain = uifigure('Visible', 'off');
            app.RSiteMain.Name = 'RSiteMain';
            app.RSiteMain.CloseRequestFcn = createCallbackFcn(app, @RSiteMainCloseRequest, true);
            app.OpenButton = uibutton(app.RSiteMain, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.FunctionsPanel = uipanel(app.RSiteMain);
            app.FunctionsPanel.Title = 'Functions';
            app.ActiveWindowLabel = uilabel(app.RSiteMain);
            app.ActiveWindowLabel.Text = 'Active Window';
            app.winNameLabel = uilabel(app.RSiteMain);
            app.winNameLabel.Text = 'winNameLabel';
            % Show the figure after all components are created
            app.RSiteMain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RSite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RSiteMain)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RSiteMain)
        end
    end
end