classdef RViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        HMAxes             matlab.ui.control.UIAxes
        PATHLabel          matlab.ui.control.Label
        XTAGLabel          matlab.ui.control.Label
        TAGAxes            matlab.ui.control.UIAxes
        XXXXMZLabel        matlab.ui.control.Label
        XXXXCELLSLabel     matlab.ui.control.Label
        NormalScaleButton  matlab.ui.control.StateButton
        ColorLimLabel      matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
        Label              matlab.ui.control.Label
        EditField_2        matlab.ui.control.NumericEditField
        DropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        rdata % Description
        scale
        clim
        nlabels
        hp
        id
        rpath
    end
    
    methods (Access = private)
        
        function draw(app)
            if app.NormalScaleButton.Value
                imagesc(app.HMAxes,app.logData);
            else
                imagesc(app.HMAxes,app.rdata.mat);            
            end
            app.HMAxes.XLim = [1,length(app.rdata.mz)]; app.HMAxes.YLim = [1,length(app.rdata.tag)];
            app.HMAxes.XTick = []; 
            app.HMAxes.YTick = [];
            colormap(app.HMAxes,app.DropDown.Value);
            app.HMAxes.CLim = app.clim;
            app.EditField.Value = app.clim(1);
            app.EditField_2.Value = app.clim(2);
            imagesc(app.TAGAxes,repmat(app.rdata.tag(:),1,100));
            app.TAGAxes.XLim = [1,100]; app.TAGAxes.YLim = [1,length(app.rdata.tag)];
            app.TAGAxes.XTick = []; 
            app.TAGAxes.YTick = [];
            colormap(app.TAGAxes,lines(app.nlabels));
            %linkaxes([app.HMAxes,app.TAGAxes],'y');
        end
        
        function results = logData(app)
            results = log10( app.rdata.mat - min(app.rdata.mat(:)) + eps );
        end
    end
    
    methods (Access = public)
        
        function results = getData(app)
            results = app.rdata;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, rdata, rpath, parent, id)
            app.rdata = rdata;
            app.PATHLabel.Text = rpath;
            app.rpath = rpath;
            app.nlabels = length(rdata.sampleNames);
            app.XTAGLabel.Text = sprintf('%d Labels',app.nlabels);
            app.XXXXCELLSLabel.Text = sprintf('%d Cells',size(rdata.mat,1));
            app.XXXXMZLabel.Text = sprintf('%d m/z', size(rdata.mat,2));
            app.clim = [min(rdata.mat(:)),max(rdata.mat(:))];
            app.draw();
            app.hp = parent;
            app.id = id;
        end

        % Value changed function: NormalScaleButton
        function NormalScaleButtonValueChanged(app, event)
            value = app.NormalScaleButton.Value;
            if value
                app.NormalScaleButton.Text = 'Log Scale';
                newdata = app.logData;
                app.clim = [min(newdata(:)),max(newdata(:))];
                app.draw(); 
            else
                app.NormalScaleButton.Text = 'Normal Scale';
                app.clim = [min(app.rdata.mat(:)),max(app.rdata.mat(:))];
                app.draw(); 
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.draw()            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.clim(1) = value;
            app.draw();
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.clim(2) = value;
            app.draw();
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.hp.setActiveFigure(app.id);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.hp.removeWindow(app.id);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.HMAxes = uiaxes(app.UIFigure);
            title(app.HMAxes, '')
            xlabel(app.HMAxes, '')
            ylabel(app.HMAxes, '')
            app.HMAxes.XTick = [];
            app.HMAxes.YTick = [];
            app.PATHLabel = uilabel(app.UIFigure);
            app.PATHLabel.Text = 'PATH';
            app.XTAGLabel = uilabel(app.UIFigure);
            app.XTAGLabel.Text = 'X-TAG';
            app.TAGAxes = uiaxes(app.UIFigure);
            title(app.TAGAxes, '')
            xlabel(app.TAGAxes, '')
            ylabel(app.TAGAxes, '')
            app.TAGAxes.XTick = [];
            app.TAGAxes.YTick = [];
            app.XXXXMZLabel = uilabel(app.UIFigure);
            app.XXXXMZLabel.Text = 'XXXX-MZ';
            app.XXXXCELLSLabel = uilabel(app.UIFigure);
            app.XXXXCELLSLabel.Text = 'XXXX-CELLS';
            app.NormalScaleButton = uibutton(app.UIFigure, 'state');
            app.NormalScaleButton.ValueChangedFcn = createCallbackFcn(app, @NormalScaleButtonValueChanged, true);
            app.NormalScaleButton.Text = 'Normal Scale';
            app.ColorLimLabel = uilabel(app.UIFigure);
            app.ColorLimLabel.Text = {'Color Lim'; ''};
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'-'; ''};
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'parula', 'jet', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'parula';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end